classdef epilepsie_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrainingOptionsPanel           matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        MaxEpochsSpinner               matlab.ui.control.Spinner
        MaxEpochsSpinnerLabel          matlab.ui.control.Label
        LSTMNetworkOptionsPanel        matlab.ui.container.Panel
        NumberOfNeuronsSpinner_2       matlab.ui.control.Spinner
        NumberOfNeuronsSpinner_2Label  matlab.ui.control.Label
        FeedForwardRecurrentNetworkOptionsPanel  matlab.ui.container.Panel
        TransferFunctionDropDown       matlab.ui.control.DropDown
        TransferFunctionDropDownLabel  matlab.ui.control.Label
        TrainingFunctionDropDown       matlab.ui.control.DropDown
        TrainingFunctionDropDownLabel  matlab.ui.control.Label
        LayersDropDown_2               matlab.ui.control.DropDown
        LayersDropDown_2Label          matlab.ui.control.Label
        NumberOfNeuronsSpinner         matlab.ui.control.Spinner
        NumberOfNeuronsSpinnerLabel    matlab.ui.control.Label
        ConvolutionalNetworkOptionsPanel  matlab.ui.container.Panel
        LayersDropDown                 matlab.ui.control.DropDown
        LayersDropDownLabel            matlab.ui.control.Label
        PoolStrideSpinner              matlab.ui.control.Spinner
        PoolStrideSpinnerLabel         matlab.ui.control.Label
        PoolSizeSpinner                matlab.ui.control.Spinner
        PoolSizeLabel                  matlab.ui.control.Label
        LayerStrideSpinner             matlab.ui.control.Spinner
        LayerStrideSpinnerLabel        matlab.ui.control.Label
        FilterSizeSpinner              matlab.ui.control.Spinner
        FilterSizeSpinnerLabel         matlab.ui.control.Label
        NumFiltersSpinner              matlab.ui.control.Spinner
        NumFiltersSpinnerLabel         matlab.ui.control.Label
        PoolDropDown                   matlab.ui.control.DropDown
        PoolDropDownLabel              matlab.ui.control.Label
        SolverNameDropDown             matlab.ui.control.DropDown
        SolverNameDropDownLabel        matlab.ui.control.Label
        NeuralNetworksforPreventingandDetectingEpilepticSeizuresLabel  matlab.ui.control.Label
        TrainNetworkButtonGroup        matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        YesButton                      matlab.ui.control.RadioButton
        NeuralNetworkButtonGroup       matlab.ui.container.ButtonGroup
        RecurrentButton                matlab.ui.control.RadioButton
        LSTMButton                     matlab.ui.control.RadioButton
        ConvolutionalButton            matlab.ui.control.RadioButton
        FeedForwardButton              matlab.ui.control.RadioButton
        RunButton                      matlab.ui.control.Button
        PatientfortestingPanel         matlab.ui.container.Panel
        PatientDropDownTesting         matlab.ui.control.DropDown
        PatientDropDown_2Label         matlab.ui.control.Label
        PatientfortrainingPanel        matlab.ui.container.Panel
        PatientDropDownTraining        matlab.ui.control.DropDown
        PatientDropDownLabel           matlab.ui.control.Label
        ResultsPanel                   matlab.ui.container.Panel
        DectectionMethod2Panel         matlab.ui.container.Panel
        SpecificityTextArea_4          matlab.ui.control.TextArea
        SpecificityTextArea_4Label     matlab.ui.control.Label
        SensitivityTextArea_4          matlab.ui.control.TextArea
        SensitivityTextArea_4Label     matlab.ui.control.Label
        PredictonMethod2Panel          matlab.ui.container.Panel
        SpecificityTextArea_3          matlab.ui.control.TextArea
        SpecificityTextArea_3Label     matlab.ui.control.Label
        SensitivityTextArea_3          matlab.ui.control.TextArea
        SensitivityTextArea_3Label     matlab.ui.control.Label
        DectectionMethod1Panel         matlab.ui.container.Panel
        SpecificityTextArea_2          matlab.ui.control.TextArea
        SpecificityTextArea_2Label_2   matlab.ui.control.Label
        SensitivityTextArea_2          matlab.ui.control.TextArea
        SensitivityTextArea_2Label     matlab.ui.control.Label
        PredictonMethod1Panel          matlab.ui.container.Panel
        SensitivityTextArea            matlab.ui.control.TextArea
        SensitivityTextArea_5Label     matlab.ui.control.Label
        SpecificityTextArea            matlab.ui.control.TextArea
        SpecificityTextArea_5Label     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            sensitivity_predicting = 0;
            specifity_predicting = 0;
            
            sensitivity_detecting = 0;
            specifity_detecting = 0;
            
            if app.NoButton.Value
                if app.PatientDropDownTesting.Value == "44202"
                    patient = load("44202.mat");
                    P = patient.FeatVectSel;
                    T = patient.Trg;
                else
                    patient = load("63502.mat");
                    P = patient.FeatVectSel;
                    T = patient.Trg;
                end
                
                if app.FeedForwardButton.Value
                   from_file = load("FFNN.mat");
                   net = from_file.ffNN;
                   
                   [sens_previsao1,espec_previsao1,sens_detecao1,espec_detecao1,sens_previsao2,espec_previsao2,sens_detecao2,espec_detecao2] = test_shallow(str2num(convertCharsToStrings(app.PatientDropDownTesting.Value)), net, 0, 0);
                   
                   app.SensitivityTextArea.Value = num2str(sens_previsao1);
                   app.SpecificityTextArea.Value = num2str(espec_previsao1);
                   
                   app.SensitivityTextArea_2.Value = num2str(sens_detecao1);
                   app.SpecificityTextArea_2.Value = num2str(espec_detecao1);
                   
                   app.SensitivityTextArea_3.Value = num2str(sens_previsao2);
                   app.SpecificityTextArea_3.Value = num2str(espec_previsao2);
                   
                   app.SensitivityTextArea_4.Value = num2str(sens_detecao2);
                   app.SpecificityTextArea_4.Value = num2str(espec_detecao2);
                end
                
                if app.RecurrentButton.Value
                   from_file = load("RNN.mat");
                   net = from_file.rNN;
                   
                   [sens_previsao1,espec_previsao1,sens_detecao1,espec_detecao1,sens_previsao2,espec_previsao2,sens_detecao2,espec_detecao2] = test_shallow(str2num(convertCharsToStrings(app.PatientDropDownTesting.Value)), net, 0, 0);
                
                   app.SensitivityTextArea.Value = num2str(sens_previsao1);
                   app.SpecificityTextArea.Value = num2str(espec_previsao1);
                   
                   app.SensitivityTextArea_2.Value = num2str(sens_detecao1);
                   app.SpecificityTextArea_2.Value = num2str(espec_detecao1);
                   
                   app.SensitivityTextArea_3.Value = num2str(sens_previsao2);
                   app.SpecificityTextArea_3.Value = num2str(espec_previsao2);
                   
                   app.SensitivityTextArea_4.Value = num2str(sens_detecao2);
                   app.SpecificityTextArea_4.Value = num2str(espec_detecao2);
                end
                
                if app.ConvolutionalButton.Value
                    from_file = load("CNN.mat");
                    net = from_file.cNN;
                    
                    [sens_previsao1,espec_previsao1,sens_detecao1,espec_detecao1,sens_previsao2,espec_previsao2,sens_detecao2,espec_detecao2] = testCNN(str2num(convertCharsToStrings(app.PatientDropDownTesting.Value)), net);
                
                   app.SensitivityTextArea.Value = num2str(sens_previsao1);
                   app.SpecificityTextArea.Value = num2str(espec_previsao1);
                   
                   app.SensitivityTextArea_2.Value = num2str(sens_detecao1);
                   app.SpecificityTextArea_2.Value = num2str(espec_detecao1);
                   
                   app.SensitivityTextArea_3.Value = num2str(sens_previsao2);
                   app.SpecificityTextArea_3.Value = num2str(espec_previsao2);
                   
                   app.SensitivityTextArea_4.Value = num2str(sens_detecao2);
                   app.SpecificityTextArea_4.Value = num2str(espec_detecao2);
                end
                
                if app.LSTMButton.Value
                   from_file = load("LSTM.mat");
                    net = from_file.net_lstm;
                    
                   [sens_previsao1,espec_previsao1,sens_detecao1,espec_detecao1,sens_previsao2,espec_previsao2,sens_detecao2,espec_detecao2] = lstm_testing(P, T, net);
                    
                   app.SensitivityTextArea.Value = num2str(sens_previsao1);
                   app.SpecificityTextArea.Value = num2str(espec_previsao1);
                   
                   app.SensitivityTextArea_2.Value = num2str(sens_detecao1);
                   app.SpecificityTextArea_2.Value = num2str(espec_detecao1);
                   
                   app.SensitivityTextArea_3.Value = num2str(sens_previsao2);
                   app.SpecificityTextArea_3.Value = num2str(espec_previsao2);
                   
                   app.SensitivityTextArea_4.Value = num2str(sens_detecao2);
                   app.SpecificityTextArea_4.Value = num2str(espec_detecao2);
                end
            
            else
                if app.PatientDropDownTraining.Value == "44202"
                    patient = load("44202.mat");
                    P = patient.FeatVectSel;
                    T = patient.Trg;
                else
                    patient = load("63502.mat");
                    P = patient.FeatVectSel;
                    T = patient.Trg;
                end
                
                if app.FeedForwardButton.Value
                   a = str2num(app.LayersDropDown_2.Value);
                   b = app.NumberOfNeuronsSpinner.Value;
                   c = convertCharsToStrings(app.TrainingFunctionDropDown.Value);
                   d = convertCharsToStrings(app.TransferFunctionDropDown.Value);
                   
                   FFNN(T, P, a, b, c, d, 0, 0);
                end
                
                if app.RecurrentButton.Value
                   a = str2num(app.LayersDropDown_2.Value);
                   b = app.NumberOfNeuronsSpinner.Value;
                   c = convertCharsToStrings(app.TrainingFunctionDropDown.Value);
                   d = convertCharsToStrings(app.TransferFunctionDropDown.Value);
                   
                   RecurrentNN(T, P, a, b, c, d, 0, 0);
                end
                
                if app.ConvolutionalButton.Value
                    a = convertCharsToStrings(app.SolverNameDropDown.Value);
                    b = app.MaxEpochsSpinner.Value;
                    c = str2num(convertCharsToStrings(app.LayersDropDown.Value));
                    d = app.NumFiltersSpinner.Value;
                    e = app.FilterSizeSpinner.Value;
                    f = app.LayerStrideSpinner.Value;
                    g = convertCharsToStrings(app.PoolDropDown.Value);
                    h = app.PoolSizeSpinner.Value;
                    i = app.PoolStrideSpinner.Value;
                    
                    convolutional_max(T, P, a, b, c, d, e, f, g, h, i);
                end
                
                if app.LSTMButton.Value
                    a = app.NumberOfNeuronsSpinner_2.Value;
                    
                    lstm_net(P, T, a);
                end
            
            
            end
            
            
        end

        % Callback function
        function NeuralNetworkButtonGroupSelectionChanged(app, event)

        end

        % Selection changed function: TrainNetworkButtonGroup
        function TrainNetworkButtonGroupSelectionChanged(app, event)
            
            if app.YesButton.Value
                set(app.TrainingOptionsPanel, 'Enable', 'on');
                set(app.PatientfortrainingPanel, 'Enable', 'on');
            else
                set(app.TrainingOptionsPanel, 'Enable', 'off');
                set(app.PatientfortrainingPanel, 'Enable', 'off');
            end
            
        end

        % Selection changed function: NeuralNetworkButtonGroup
        function NeuralNetworkButtonGroupSelectionChanged2(app, event)
            
            if app.RecurrentButton.Value
                set(app.LSTMNetworkOptionsPanel, 'Enable', 'off');
                set(app.ConvolutionalNetworkOptionsPanel, 'Enable', 'off');
                set(app.FeedForwardRecurrentNetworkOptionsPanel, 'Enable', 'on');
            end
            
            if app.LSTMButton.Value
                set(app.LSTMNetworkOptionsPanel, 'Enable', 'on');
                set(app.ConvolutionalNetworkOptionsPanel, 'Enable', 'off');
                set(app.FeedForwardRecurrentNetworkOptionsPanel, 'Enable', 'off');
            end
            
            if app.ConvolutionalButton.Value
                set(app.LSTMNetworkOptionsPanel, 'Enable', 'off');
                set(app.ConvolutionalNetworkOptionsPanel, 'Enable', 'on');
                set(app.FeedForwardRecurrentNetworkOptionsPanel, 'Enable', 'off');
            end
            
            if app.FeedForwardButton.Value
                set(app.LSTMNetworkOptionsPanel, 'Enable', 'off');
                set(app.ConvolutionalNetworkOptionsPanel, 'Enable', 'off');
                set(app.FeedForwardRecurrentNetworkOptionsPanel, 'Enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.PredictonMethod1Panel = uipanel(app.ResultsPanel);
            app.PredictonMethod1Panel.Title = 'Predicton - Method 1';
            app.SpecificityTextArea_5Label = uilabel(app.PredictonMethod1Panel);
            app.SpecificityTextArea_5Label.Text = 'Specificity';
            app.SpecificityTextArea = uitextarea(app.PredictonMethod1Panel);
            app.SensitivityTextArea_5Label = uilabel(app.PredictonMethod1Panel);
            app.SensitivityTextArea_5Label.Text = 'Sensitivity';
            app.SensitivityTextArea = uitextarea(app.PredictonMethod1Panel);
            app.DectectionMethod1Panel = uipanel(app.ResultsPanel);
            app.DectectionMethod1Panel.Title = 'Dectection - Method 1';
            app.SensitivityTextArea_2Label = uilabel(app.DectectionMethod1Panel);
            app.SensitivityTextArea_2Label.Text = 'Sensitivity';
            app.SensitivityTextArea_2 = uitextarea(app.DectectionMethod1Panel);
            app.SpecificityTextArea_2Label_2 = uilabel(app.DectectionMethod1Panel);
            app.SpecificityTextArea_2Label_2.Text = 'Specificity';
            app.SpecificityTextArea_2 = uitextarea(app.DectectionMethod1Panel);
            app.PredictonMethod2Panel = uipanel(app.ResultsPanel);
            app.PredictonMethod2Panel.Title = 'Predicton - Method 2';
            app.SensitivityTextArea_3Label = uilabel(app.PredictonMethod2Panel);
            app.SensitivityTextArea_3Label.Text = 'Sensitivity';
            app.SensitivityTextArea_3 = uitextarea(app.PredictonMethod2Panel);
            app.SpecificityTextArea_3Label = uilabel(app.PredictonMethod2Panel);
            app.SpecificityTextArea_3Label.Text = 'Specificity';
            app.SpecificityTextArea_3 = uitextarea(app.PredictonMethod2Panel);
            app.DectectionMethod2Panel = uipanel(app.ResultsPanel);
            app.DectectionMethod2Panel.Title = 'Dectection - Method 2';
            app.SensitivityTextArea_4Label = uilabel(app.DectectionMethod2Panel);
            app.SensitivityTextArea_4Label.Text = 'Sensitivity';
            app.SensitivityTextArea_4 = uitextarea(app.DectectionMethod2Panel);
            app.SpecificityTextArea_4Label = uilabel(app.DectectionMethod2Panel);
            app.SpecificityTextArea_4Label.Text = 'Specificity';
            app.SpecificityTextArea_4 = uitextarea(app.DectectionMethod2Panel);
            app.PatientfortrainingPanel = uipanel(app.UIFigure);
            app.PatientfortrainingPanel.Title = 'Patient for training';
            app.PatientDropDownLabel = uilabel(app.PatientfortrainingPanel);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDownTraining = uidropdown(app.PatientfortrainingPanel);
            app.PatientDropDownTraining.Items = {'44202', '63502'};
            app.PatientDropDownTraining.Value = '44202';
            app.PatientfortestingPanel = uipanel(app.UIFigure);
            app.PatientfortestingPanel.Title = 'Patient for testing';
            app.PatientDropDown_2Label = uilabel(app.PatientfortestingPanel);
            app.PatientDropDown_2Label.Text = 'Patient';
            app.PatientDropDownTesting = uidropdown(app.PatientfortestingPanel);
            app.PatientDropDownTesting.Items = {'44202', '63502'};
            app.PatientDropDownTesting.Value = '44202';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NeuralNetworkButtonGroup = uibuttongroup(app.UIFigure);
            app.NeuralNetworkButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NeuralNetworkButtonGroupSelectionChanged2, true);
            app.NeuralNetworkButtonGroup.Title = 'Neural Network';
            app.FeedForwardButton = uiradiobutton(app.NeuralNetworkButtonGroup);
            app.FeedForwardButton.Tag = 'Multilayer';
            app.FeedForwardButton.Text = 'Feed Forward';
            app.FeedForwardButton.Value = true;
            app.ConvolutionalButton = uiradiobutton(app.NeuralNetworkButtonGroup);
            app.ConvolutionalButton.Tag = 'Convolutional';
            app.ConvolutionalButton.Text = 'Convolutional';
            app.LSTMButton = uiradiobutton(app.NeuralNetworkButtonGroup);
            app.LSTMButton.Text = 'LSTM';
            app.RecurrentButton = uiradiobutton(app.NeuralNetworkButtonGroup);
            app.RecurrentButton.Text = 'Recurrent';
            app.TrainNetworkButtonGroup = uibuttongroup(app.UIFigure);
            app.TrainNetworkButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrainNetworkButtonGroupSelectionChanged, true);
            app.TrainNetworkButtonGroup.Title = 'Train Network';
            app.YesButton = uiradiobutton(app.TrainNetworkButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.TrainNetworkButtonGroup);
            app.NoButton.Text = 'No';
            app.NeuralNetworksforPreventingandDetectingEpilepticSeizuresLabel = uilabel(app.UIFigure);
            app.NeuralNetworksforPreventingandDetectingEpilepticSeizuresLabel.Text = 'Neural Networks for Preventing and Detecting Epileptic Seizures';
            app.TrainingOptionsPanel = uipanel(app.UIFigure);
            app.TrainingOptionsPanel.Title = 'Training Options';
            app.ConvolutionalNetworkOptionsPanel = uipanel(app.TrainingOptionsPanel);
            app.ConvolutionalNetworkOptionsPanel.Title = 'Convolutional Network Options';
            app.SolverNameDropDownLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.SolverNameDropDownLabel.Text = {'Solver'; 'Name'};
            app.SolverNameDropDown = uidropdown(app.ConvolutionalNetworkOptionsPanel);
            app.SolverNameDropDown.Items = {'adam', 'sgdm'};
            app.SolverNameDropDown.Value = 'adam';
            app.PoolDropDownLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.PoolDropDownLabel.Text = 'Pool';
            app.PoolDropDown = uidropdown(app.ConvolutionalNetworkOptionsPanel);
            app.PoolDropDown.Items = {'max', 'avarage'};
            app.PoolDropDown.Value = 'max';
            app.NumFiltersSpinnerLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.NumFiltersSpinnerLabel.Text = {'Num'; 'Filters'};
            app.NumFiltersSpinner = uispinner(app.ConvolutionalNetworkOptionsPanel);
            app.NumFiltersSpinner.Limits = [1 Inf];
            app.NumFiltersSpinner.Value = 1;
            app.FilterSizeSpinnerLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.FilterSizeSpinnerLabel.Text = {'Filter'; 'Size'};
            app.FilterSizeSpinner = uispinner(app.ConvolutionalNetworkOptionsPanel);
            app.FilterSizeSpinner.Limits = [1 Inf];
            app.FilterSizeSpinner.Value = 1;
            app.LayerStrideSpinnerLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.LayerStrideSpinnerLabel.Text = {'Layer'; 'Stride'};
            app.LayerStrideSpinner = uispinner(app.ConvolutionalNetworkOptionsPanel);
            app.LayerStrideSpinner.Limits = [1 Inf];
            app.LayerStrideSpinner.Value = 1;
            app.PoolSizeLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.PoolSizeLabel.Text = 'Pool Size';
            app.PoolSizeSpinner = uispinner(app.ConvolutionalNetworkOptionsPanel);
            app.PoolSizeSpinner.Limits = [1 Inf];
            app.PoolSizeSpinner.Value = 1;
            app.PoolStrideSpinnerLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.PoolStrideSpinnerLabel.Text = 'Pool Stride';
            app.PoolStrideSpinner = uispinner(app.ConvolutionalNetworkOptionsPanel);
            app.PoolStrideSpinner.Limits = [1 Inf];
            app.PoolStrideSpinner.Value = 1;
            app.LayersDropDownLabel = uilabel(app.ConvolutionalNetworkOptionsPanel);
            app.LayersDropDownLabel.Text = 'Layers';
            app.LayersDropDown = uidropdown(app.ConvolutionalNetworkOptionsPanel);
            app.LayersDropDown.Items = {'1', '2', '3', '4', '5'};
            app.LayersDropDown.Value = '1';
            app.FeedForwardRecurrentNetworkOptionsPanel = uipanel(app.TrainingOptionsPanel);
            app.FeedForwardRecurrentNetworkOptionsPanel.Title = 'Feed Forward\Recurrent Network Options';
            app.NumberOfNeuronsSpinnerLabel = uilabel(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.NumberOfNeuronsSpinnerLabel.Text = {'Number Of'; 'Neurons'};
            app.NumberOfNeuronsSpinner = uispinner(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.NumberOfNeuronsSpinner.Limits = [1 Inf];
            app.NumberOfNeuronsSpinner.Value = 1;
            app.LayersDropDown_2Label = uilabel(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.LayersDropDown_2Label.Text = 'Layers';
            app.LayersDropDown_2 = uidropdown(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.LayersDropDown_2.Items = {'1', '2', '3'};
            app.LayersDropDown_2.Value = '1';
            app.TrainingFunctionDropDownLabel = uilabel(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.TrainingFunctionDropDownLabel.Text = 'Training Function';
            app.TrainingFunctionDropDown = uidropdown(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.TrainingFunctionDropDown.Items = {'trainscg', 'trainlm', 'traingd'};
            app.TrainingFunctionDropDown.Value = 'trainscg';
            app.TransferFunctionDropDownLabel = uilabel(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.TransferFunctionDropDownLabel.Text = 'Transfer Function';
            app.TransferFunctionDropDown = uidropdown(app.FeedForwardRecurrentNetworkOptionsPanel);
            app.TransferFunctionDropDown.Items = {'purelin', 'logsig', 'tansig'};
            app.TransferFunctionDropDown.Value = 'purelin';
            app.LSTMNetworkOptionsPanel = uipanel(app.TrainingOptionsPanel);
            app.LSTMNetworkOptionsPanel.Title = 'LSTM Network Options';
            app.NumberOfNeuronsSpinner_2Label = uilabel(app.LSTMNetworkOptionsPanel);
            app.NumberOfNeuronsSpinner_2Label.Text = {'Number Of'; 'Neurons'};
            app.NumberOfNeuronsSpinner_2 = uispinner(app.LSTMNetworkOptionsPanel);
            app.NumberOfNeuronsSpinner_2.Limits = [1 Inf];
            app.NumberOfNeuronsSpinner_2.Value = 1;
            app.Panel = uipanel(app.TrainingOptionsPanel);
            app.MaxEpochsSpinnerLabel = uilabel(app.Panel);
            app.MaxEpochsSpinnerLabel.Text = {'Max'; 'Epochs'};
            app.MaxEpochsSpinner = uispinner(app.Panel);
            app.MaxEpochsSpinner.Limits = [1 Inf];
            app.MaxEpochsSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epilepsie_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end