classdef Machine_Learning_app_project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EditField_6                   matlab.ui.control.EditField
        Button                        matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OnelayerNeuralNetworksTab     matlab.ui.container.Tab
        EditField_2                   matlab.ui.control.EditField
        BinaryButton                  matlab.ui.control.Button
        SigmoidalButton               matlab.ui.control.Button
        LinearButton                  matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        TwolayersNeuralNetwokrsTab    matlab.ui.container.Tab
        Switch3                       matlab.ui.control.Switch
        Switch2                       matlab.ui.control.Switch
        Switch                        matlab.ui.control.Switch
        AssociativememoryButton       matlab.ui.control.Button
        BinaryperceptronfilterButton  matlab.ui.control.Button
        SemfiltroButton               matlab.ui.control.Button
        EditField_4                   matlab.ui.control.EditField
        BinaryButton_2                matlab.ui.control.Button
        SigmoidalButton_2             matlab.ui.control.Button
        LinearButton_2                matlab.ui.control.Button
        EditField_3                   matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigmoidalButton
        function SigmoidalButtonPushed(app, event)
            app.EditField_2.Value="modle currently not avaible";
            app.EditField_6.Value="modle currently not avaible";
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            app.EditField_2.Value="modle currently not avaible";
            app.EditField_6.Value="modle currently not avaible";
        end

        % Button pushed function: LinearButton
        function LinearButtonPushed(app, event)
            app.EditField_2.Value="purelin";
            app.EditField_6.Value=strcat("Currently using: net_purelin_1L");
        end

        % Button pushed function: SigmoidalButton_2
        function SigmoidalButton_2Pushed(app, event)
            app.EditField_4.Value="logsig";
            app.EditField_6.Value=strcat("Currently using: net_purelin_",app.EditField_4.Value,"_2L");
        end

        % Value changing function: EditField_4
        function EditField_4ValueChanging(app, event)

        end

        % Button pushed function: LinearButton_2
        function LinearButton_2Pushed(app, event)
            app.EditField_4.Value="purelin";
            app.EditField_6.Value=strcat("Currently using: net_purelin_",app.EditField_4.Value,"_2L");
        end

        % Button pushed function: BinaryButton_2
        function BinaryButton_2Pushed(app, event)
            app.EditField_4.Value="softmax";
            app.EditField_6.Value=strcat("Currently using: net_purelin_",app.EditField_4.Value,"_2L");
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            noFilter = app.Switch.Value;
            app.Switch2.Value='Off';
            app.Switch3.Value='Off';
            perceptron=app.Switch2.Value;
            associativeMemory=app.Switch3.Value;
            save("myClassifyArguments/perceptron.mat","perceptron");
            save("myClassifyArguments/noFilter.mat","noFilter");
            save("myClassifyArguments/associativeMemory.mat","associativeMemory");
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            perceptron = app.Switch2.Value;
            app.Switch.Value='Off';
            app.Switch3.Value='Off';
            noFilter=app.Switch.Value;
            associativeMemory=app.Switch3.Value;
            save("myClassifyArguments/perceptron.mat","perceptron");
            save("myClassifyArguments/noFilter.mat","noFilter");
            save("myClassifyArguments/associativeMemory.mat","associativeMemory");
            
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            associativeMemory = app.Switch3.Value;
            app.Switch2.Value='Off';
            app.Switch.Value='Off';
            noFilter=app.Switch.Value;
            perceptron=app.Switch2.Value;
            save("myClassifyArguments/perceptron.mat","perceptron");
            save("myClassifyArguments/noFilter.mat","noFilter");
            save("myClassifyArguments/associativeMemory.mat","associativeMemory");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            b=split(app.EditField_6.Value);
            a= load(strcat("models/classifiers/",b(3)),"net");
            net=a.net;
            save("models/TESTE.mat","net");
            mpaper;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OnelayerNeuralNetworksTab = uitab(app.TabGroup);
            app.OnelayerNeuralNetworksTab.Title = 'One layer Neural Networks';
            app.EditField = uieditfield(app.OnelayerNeuralNetworksTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Activiation Functions';
            app.LinearButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.LinearButton.ButtonPushedFcn = createCallbackFcn(app, @LinearButtonPushed, true);
            app.LinearButton.Text = 'Linear';
            app.SigmoidalButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.SigmoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButtonPushed, true);
            app.SigmoidalButton.Text = 'Sigmoidal';
            app.BinaryButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.EditField_2 = uieditfield(app.OnelayerNeuralNetworksTab, 'text');
            app.EditField_2.Editable = 'off';
            app.TwolayersNeuralNetwokrsTab = uitab(app.TabGroup);
            app.TwolayersNeuralNetwokrsTab.Title = 'Two layers Neural Netwokrs';
            app.EditField_3 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Activiation Functions';
            app.LinearButton_2 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.LinearButton_2.ButtonPushedFcn = createCallbackFcn(app, @LinearButton_2Pushed, true);
            app.LinearButton_2.Text = 'Linear';
            app.SigmoidalButton_2 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.SigmoidalButton_2.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButton_2Pushed, true);
            app.SigmoidalButton_2.Text = 'Sigmoidal';
            app.BinaryButton_2 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.BinaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @BinaryButton_2Pushed, true);
            app.BinaryButton_2.Text = 'Binary';
            app.EditField_4 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.ValueChangingFcn = createCallbackFcn(app, @EditField_4ValueChanging, true);
            app.EditField_4.Editable = 'off';
            app.SemfiltroButton = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.SemfiltroButton.Text = 'Sem filtro';
            app.BinaryperceptronfilterButton = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.BinaryperceptronfilterButton.Text = 'Binary perceptron filter';
            app.AssociativememoryButton = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.AssociativememoryButton.Text = 'Associative memory';
            app.Switch = uiswitch(app.TwolayersNeuralNetwokrsTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Switch2 = uiswitch(app.TwolayersNeuralNetwokrsTab, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3 = uiswitch(app.TwolayersNeuralNetwokrsTab, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Currently in use:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Machine_Learning_app_project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end