classdef app_OCR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        EditField_6                   matlab.ui.control.EditField
        MpaperButton                  matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OnelayerNeuralNetworksTab     matlab.ui.container.Tab
        Switch3                       matlab.ui.control.Switch
        Switch2                       matlab.ui.control.Switch
        Switch                        matlab.ui.control.Switch
        AssociativememoryButton       matlab.ui.control.Button
        BinaryperceptronfilterButton  matlab.ui.control.Button
        SemfiltroButton               matlab.ui.control.Button
        EditField_2                   matlab.ui.control.EditField
        BinaryButton                  matlab.ui.control.Button
        SigmoidalButton               matlab.ui.control.Button
        LinearButton                  matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        TwolayersNeuralNetwokrsTab    matlab.ui.container.Tab
        ReadyButton                   matlab.ui.control.Button
        EditField_8                   matlab.ui.control.EditField
        SoftmaxButton                 matlab.ui.control.Button
        SigmoidalButton_3             matlab.ui.control.Button
        LinearButton_3                matlab.ui.control.Button
        EditField_7                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        SigmoidalButton_2             matlab.ui.control.Button
        LinearButton_2                matlab.ui.control.Button
        EditField_3                   matlab.ui.control.EditField
        Image_2                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigmoidalButton
        function SigmoidalButtonPushed(app, event)
            app.EditField_2.Value="logsig";
            app.EditField_6.Value=strcat("Currently using: net_logsig_1L");
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            app.EditField_2.Value="hardlim";
            app.EditField_6.Value=strcat("Currently using: net_hardlim_1L");
        end

        % Button pushed function: LinearButton
        function LinearButtonPushed(app, event)
            app.EditField_2.Value="purelin";
            app.EditField_6.Value=strcat("Currently using: net_purelin_1L");
        end

        % Button pushed function: SigmoidalButton_2
        function SigmoidalButton_2Pushed(app, event)
            app.EditField_4.Value="logsig";
        end

        % Button pushed function: LinearButton_2
        function LinearButton_2Pushed(app, event)
            app.EditField_4.Value="purelin";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            noFilter = app.Switch.Value;
            app.Switch2.Value='Off';
            app.Switch3.Value='Off';
            app.EditField_6.Value=strcat("Currently using: net_",app.EditField_2.Value,"_1L");
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            perceptron = app.Switch2.Value;
            app.Switch.Value='Off';
            app.Switch3.Value='Off';
            app.EditField_6.Value=strcat("Currently using: net_",app.EditField_2.Value,"_1L");
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            associativeMemory = app.Switch3.Value;
            app.Switch2.Value='Off';
            app.Switch.Value='Off';
            app.EditField_6.Value=strcat("Currently using: net_",app.EditField_2.Value,"_1L");
        end

        % Button pushed function: MpaperButton
        function MpaperButtonPushed(app, event)
            perceptron=app.Switch2.Value;
            associativeMemory=app.Switch3.Value;
            noFilter=app.Switch.Value;
            save("myClassifyArguments/perceptron.mat","perceptron");
            save("myClassifyArguments/noFilter.mat","noFilter");
            save("myClassifyArguments/associativeMemory.mat","associativeMemory");
            b=split(app.EditField_6.Value);
            a= load(strcat("models/classifiers/",b(3)),"net");
            net=a.net;
            save("models/TESTE.mat","net");
            mpaper;
        end

        % Button pushed function: SigmoidalButton_3
        function SigmoidalButton_3Pushed(app, event)
            app.EditField_8.Value="logsig";
        end

        % Button pushed function: LinearButton_3
        function LinearButton_3Pushed(app, event)
            app.EditField_8.Value="purelin";
        end

        % Button pushed function: SoftmaxButton
        function SoftmaxButtonPushed(app, event)
            app.EditField_8.Value="softmax";
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            app.EditField_6.Value=strcat("Currently using: net_",app.EditField_4.Value,"_",app.EditField_8.Value,"_2L.mat");
            app.Switch.Value='On';
            app.Switch2.Value='Off';
            app.Switch3.Value='Off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OnelayerNeuralNetworksTab = uitab(app.TabGroup);
            app.OnelayerNeuralNetworksTab.Title = 'One layer Neural Networks';
            app.Image = uiimage(app.OnelayerNeuralNetworksTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '394db98db963b5ca3681ab4ecb16bb1d.jpg');
            app.EditField = uieditfield(app.OnelayerNeuralNetworksTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Activiation Functions';
            app.LinearButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.LinearButton.ButtonPushedFcn = createCallbackFcn(app, @LinearButtonPushed, true);
            app.LinearButton.Text = 'Linear';
            app.SigmoidalButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.SigmoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButtonPushed, true);
            app.SigmoidalButton.Text = 'Sigmoidal';
            app.BinaryButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.EditField_2 = uieditfield(app.OnelayerNeuralNetworksTab, 'text');
            app.EditField_2.Editable = 'off';
            app.SemfiltroButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.SemfiltroButton.Text = 'Sem filtro';
            app.BinaryperceptronfilterButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.BinaryperceptronfilterButton.Text = 'Binary perceptron filter';
            app.AssociativememoryButton = uibutton(app.OnelayerNeuralNetworksTab, 'push');
            app.AssociativememoryButton.Text = 'Associative memory';
            app.Switch = uiswitch(app.OnelayerNeuralNetworksTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Switch2 = uiswitch(app.OnelayerNeuralNetworksTab, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3 = uiswitch(app.OnelayerNeuralNetworksTab, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            app.TwolayersNeuralNetwokrsTab = uitab(app.TabGroup);
            app.TwolayersNeuralNetwokrsTab.Title = 'Two layers Neural Netwokrs';
            app.Image_2 = uiimage(app.TwolayersNeuralNetwokrsTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '394db98db963b5ca3681ab4ecb16bb1d.jpg');
            app.EditField_3 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Activiation Functions';
            app.LinearButton_2 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.LinearButton_2.ButtonPushedFcn = createCallbackFcn(app, @LinearButton_2Pushed, true);
            app.LinearButton_2.Text = 'Linear';
            app.SigmoidalButton_2 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.SigmoidalButton_2.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButton_2Pushed, true);
            app.SigmoidalButton_2.Text = 'Sigmoidal';
            app.EditField_4 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_7 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Activiation Functions';
            app.LinearButton_3 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.LinearButton_3.ButtonPushedFcn = createCallbackFcn(app, @LinearButton_3Pushed, true);
            app.LinearButton_3.Text = 'Linear';
            app.SigmoidalButton_3 = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.SigmoidalButton_3.ButtonPushedFcn = createCallbackFcn(app, @SigmoidalButton_3Pushed, true);
            app.SigmoidalButton_3.Text = 'Sigmoidal';
            app.SoftmaxButton = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.SoftmaxButton.ButtonPushedFcn = createCallbackFcn(app, @SoftmaxButtonPushed, true);
            app.SoftmaxButton.Text = 'Softmax';
            app.EditField_8 = uieditfield(app.TwolayersNeuralNetwokrsTab, 'text');
            app.EditField_8.Editable = 'off';
            app.ReadyButton = uibutton(app.TwolayersNeuralNetwokrsTab, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            app.MpaperButton = uibutton(app.UIFigure, 'push');
            app.MpaperButton.ButtonPushedFcn = createCallbackFcn(app, @MpaperButtonPushed, true);
            app.MpaperButton.Text = 'Mpaper';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Currently in use:';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logomarca_1290.1200x0.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_OCR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end