classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_36                    matlab.ui.control.EditField
        EditField_35                    matlab.ui.control.EditField
        ResultsPanel                    matlab.ui.container.Panel
        EditField_38                    matlab.ui.control.EditField
        EditField_37                    matlab.ui.control.EditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        NumberofhiddenneuronsEditField  matlab.ui.control.NumericEditField
        NumberofhiddenneuronsEditFieldLabel  matlab.ui.control.Label
        EditField_30                    matlab.ui.control.NumericEditField
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        EditField_29                    matlab.ui.control.NumericEditField
        SoftMaxCheckBox                 matlab.ui.control.CheckBox
        EditField_24                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        ErrorWeitghsCheckBox            matlab.ui.control.CheckBox
        EncodingCheckBox                matlab.ui.control.CheckBox
        DatasetbalanceCheckBox          matlab.ui.control.CheckBox
        LearnfunctionDropDown_2         matlab.ui.control.DropDown
        LearnfunctionDropDownLabel_2    matlab.ui.control.Label
        ActivationfunctionDropDown_4    matlab.ui.control.DropDown
        ActivationfunctionDropDown_2Label_2  matlab.ui.control.Label
        EditField_14                    matlab.ui.control.EditField
        EditField_13                    matlab.ui.control.EditField
        ActivationfunctionDropDown_3    matlab.ui.control.DropDown
        ActivationfunctionDropDownLabel_2  matlab.ui.control.Label
        EditField_12                    matlab.ui.control.EditField
        SelectapatientDropDown          matlab.ui.control.DropDown
        SelectapatientDropDownLabel     matlab.ui.control.Label
        ShallowNetworksPanel            matlab.ui.container.Panel
        EditField_15                    matlab.ui.control.NumericEditField
        ArchitectureDropDown            matlab.ui.control.DropDown
        ArchitectureDropDownLabel       matlab.ui.control.Label
        ThirdlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        ThirdlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        SecondlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        SecondlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        FirstlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        FirstlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        NumberoflayersDropDown          matlab.ui.control.DropDown
        NumberoflayersDropDownLabel     matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DeepNetworksPanel               matlab.ui.container.Panel
        ArchitectureDropDown_2          matlab.ui.control.DropDown
        ArchitectureDropDown_2Label     matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        EditField_20                    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectapatientDropDown
        function SelectapatientDropDownValueChanged(app, event)
            value = app.SelectapatientDropDown.Value;
            if(value == "44202")
                app.EditField_20.Value=1;
            else
                app.EditField_20.Value=2;
            end
        end

        % Value changed function: ArchitectureDropDown
        function ArchitectureDropDownValueChanged(app, event)
            value = app.ArchitectureDropDown.Value;
            if(value=="feedforwadnet")
                 app.EditField_15.Value=1;
            else
                 app.EditField_15.Value=2;
            end
            app.NumberoflayersDropDown.Enable="on";
            app.FirstlayeractivationfunctionDropDown.Enable="on";
            app.SecondlayeractivationfunctionDropDown.Enable="off";
            app.ThirdlayeractivationfunctionDropDown.Enable="off";
            app.NumberoflayersDropDown.Value="1";
        end

        % Value changed function: NumberoflayersDropDown
        function NumberoflayersDropDownValueChanged(app, event)
            value = app.NumberoflayersDropDown.Value;
            if(value=="1")
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="off";
                app.ThirdlayeractivationfunctionDropDown.Enable="off";
            elseif(value=="2")
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="on";
                app.ThirdlayeractivationfunctionDropDown.Enable="off";
            else
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="on";
                app.ThirdlayeractivationfunctionDropDown.Enable="on";
            end
        end

        % Value changed function: ActivationfunctionDropDown_3
        function ActivationfunctionDropDown_3ValueChanged(app, event)
            app.CheckBox.Value=1;
            app.CheckBox_2.Value=0;
            app.EditField_30.Value=1;
        end

        % Value changed function: ActivationfunctionDropDown_4
        function ActivationfunctionDropDown_4ValueChanged(app, event)
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=1;
            app.EditField_30.Value=1;
        end

        % Value changed function: LearnfunctionDropDown_2
        function LearnfunctionDropDown_2ValueChanged(app, event)
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=1;
            app.EditField_30.Value=1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(floor(str2double(app.NumberoflayersDropDown))<2)
                   actFun2="";
                   actFun3="";
                   "foi a"
            elseif(floor(str2double(app.NumberoflayersDropDown))<3)
                    actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                    actFun3="";
                    "foi b"
            else
                   actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                   actFun3=app.ThirdlayeractivationfunctionDropDown.Value;
                   "foi c"
            end
            if(app.CheckBox.Value)
                trainFun=app.ActivationfunctionDropDown_3.Value;
                learnFun="";
                "foi a"
            else
                trainFun=app.ActivationfunctionDropDown_4.Value;
                learnFun=app.ActivationfunctionDropDown_2.Value;
                "foi b"
            end


            if(app.EditField_15==1 || app.EditField_15==2)
                [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value]=shallowClassify(app.EditField_20,app.DatasetbalanceCheckBox, app.ErrorWeitghsCheckBox, app.EncodingCheckBox, app.EditField_15, app.EditField_30 , ...
                                  trainFun, learnFun, floor(str2double(app.NumberoflayersDropDown)), app.NumberofhiddenneuronsEditField.Value, app.FirstlayeractivationfunctionDropDown.Value, actFun2, actFun3, app.SoftMaxCheckBox.Value);
                "foi a"
            elseif(app.EditField_15==3)
                "foi b"
            else
                "foi c"
            end
        end

        % Value changed function: ArchitectureDropDown_2
        function ArchitectureDropDown_2ValueChanged(app, event)
            value = app.ArchitectureDropDown_2.Value;
            if(value=="CNN")
                 app.EditField_15.Value=3;
            else
                 app.EditField_15.Value=4;
            end
            app.NumberoflayersDropDown.Enable="off";
            app.FirstlayeractivationfunctionDropDown.Enable="off";
            app.SecondlayeractivationfunctionDropDown.Enable="off";
            app.ThirdlayeractivationfunctionDropDown.Enable="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20.Editable = 'off';
            app.EditField_20.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '25553409-fundo-colorido-abstrato-design-de-fundo-de-cor-suave-lindo-grunge-azul-aquarela-tela-de-aquarela-texturizada-de-papel-aquarela-para-design-criativo-moderno-fundo-com-raios-vetor.jpg');
            app.DeepNetworksPanel = uipanel(app.UIFigure);
            app.DeepNetworksPanel.Title = 'Deep Networks';
            app.ArchitectureDropDown_2Label = uilabel(app.DeepNetworksPanel);
            app.ArchitectureDropDown_2Label.Text = 'Architecture';
            app.ArchitectureDropDown_2 = uidropdown(app.DeepNetworksPanel);
            app.ArchitectureDropDown_2.Items = {'CNN', 'LSTM'};
            app.ArchitectureDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ArchitectureDropDown_2ValueChanged, true);
            app.ArchitectureDropDown_2.Value = 'CNN';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images.png');
            app.Button.Text = '';
            app.ShallowNetworksPanel = uipanel(app.UIFigure);
            app.ShallowNetworksPanel.Title = 'Shallow Networks';
            app.NumberoflayersDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.NumberoflayersDropDownLabel.Text = 'Number of layers';
            app.NumberoflayersDropDown = uidropdown(app.ShallowNetworksPanel);
            app.NumberoflayersDropDown.Items = {'1', '2', '3'};
            app.NumberoflayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberoflayersDropDownValueChanged, true);
            app.NumberoflayersDropDown.Value = '1';
            app.FirstlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.FirstlayeractivationfunctionDropDownLabel.Text = 'First layer activation function';
            app.FirstlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.FirstlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.FirstlayeractivationfunctionDropDown.Value = 'purelin';
            app.SecondlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.SecondlayeractivationfunctionDropDownLabel.Enable = 'off';
            app.SecondlayeractivationfunctionDropDownLabel.Text = 'Second layer activation function';
            app.SecondlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.SecondlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.SecondlayeractivationfunctionDropDown.Enable = 'off';
            app.SecondlayeractivationfunctionDropDown.Value = 'purelin';
            app.ThirdlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.ThirdlayeractivationfunctionDropDownLabel.Enable = 'off';
            app.ThirdlayeractivationfunctionDropDownLabel.Text = 'Third layer activation function';
            app.ThirdlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.ThirdlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.ThirdlayeractivationfunctionDropDown.Enable = 'off';
            app.ThirdlayeractivationfunctionDropDown.Value = 'purelin';
            app.ArchitectureDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.ArchitectureDropDownLabel.Text = 'Architecture';
            app.ArchitectureDropDown = uidropdown(app.ShallowNetworksPanel);
            app.ArchitectureDropDown.Items = {'feedforwardnret', 'layrecnet'};
            app.ArchitectureDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchitectureDropDownValueChanged, true);
            app.ArchitectureDropDown.Value = 'feedforwardnret';
            app.EditField_15 = uieditfield(app.ShallowNetworksPanel, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_15.Visible = 'off';
            app.EditField_15.Value = 1;
            app.SelectapatientDropDownLabel = uilabel(app.UIFigure);
            app.SelectapatientDropDownLabel.Text = 'Select a patient';
            app.SelectapatientDropDown = uidropdown(app.UIFigure);
            app.SelectapatientDropDown.Items = {'44202', '63502'};
            app.SelectapatientDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectapatientDropDownValueChanged, true);
            app.SelectapatientDropDown.Value = '44202';
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.Value = 'Bacth training';
            app.ActivationfunctionDropDownLabel_2 = uilabel(app.UIFigure);
            app.ActivationfunctionDropDownLabel_2.Text = 'Train function';
            app.ActivationfunctionDropDown_3 = uidropdown(app.UIFigure);
            app.ActivationfunctionDropDown_3.Items = {'traingd', 'traingdm', 'trainlm'};
            app.ActivationfunctionDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionDropDown_3ValueChanged, true);
            app.ActivationfunctionDropDown_3.Value = 'traingd';
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.EditField_13.Value = 'Please select a training style: ';
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_14.Value = 'Incremental training';
            app.ActivationfunctionDropDown_2Label_2 = uilabel(app.UIFigure);
            app.ActivationfunctionDropDown_2Label_2.Text = 'Train function';
            app.ActivationfunctionDropDown_4 = uidropdown(app.UIFigure);
            app.ActivationfunctionDropDown_4.Items = {'trainc', 'trainr'};
            app.ActivationfunctionDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionDropDown_4ValueChanged, true);
            app.ActivationfunctionDropDown_4.Value = 'trainc';
            app.LearnfunctionDropDownLabel_2 = uilabel(app.UIFigure);
            app.LearnfunctionDropDownLabel_2.Text = 'Learn function';
            app.LearnfunctionDropDown_2 = uidropdown(app.UIFigure);
            app.LearnfunctionDropDown_2.Items = {'learngd', 'learngdm'};
            app.LearnfunctionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LearnfunctionDropDown_2ValueChanged, true);
            app.LearnfunctionDropDown_2.Value = 'learngd';
            app.DatasetbalanceCheckBox = uicheckbox(app.UIFigure);
            app.DatasetbalanceCheckBox.Text = 'Data set balance';
            app.EncodingCheckBox = uicheckbox(app.UIFigure);
            app.EncodingCheckBox.Text = 'Encoding';
            app.ErrorWeitghsCheckBox = uicheckbox(app.UIFigure);
            app.ErrorWeitghsCheckBox.Text = 'Error Weitghs';
            app.EditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_22.Editable = 'off';
            app.EditField_22.Visible = 'off';
            app.EditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_23.Editable = 'off';
            app.EditField_23.Visible = 'off';
            app.EditField_24 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_24.Editable = 'off';
            app.EditField_24.Visible = 'off';
            app.SoftMaxCheckBox = uicheckbox(app.UIFigure);
            app.SoftMaxCheckBox.Text = 'SoftMax';
            app.EditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_29.Editable = 'off';
            app.EditField_29.Visible = 'off';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.EditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_30.Editable = 'off';
            app.EditField_30.Visible = 'off';
            app.NumberofhiddenneuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofhiddenneuronsEditFieldLabel.Text = 'Number of hidden neurons';
            app.NumberofhiddenneuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofhiddenneuronsEditField.Limits = [1 50];
            app.NumberofhiddenneuronsEditField.Value = 25;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results:';
            app.EditField_31 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_31.Editable = 'off';
            app.EditField_32 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_32.Editable = 'off';
            app.EditField_33 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_33.Editable = 'off';
            app.EditField_34 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_34.Editable = 'off';
            app.EditField_37 = uieditfield(app.ResultsPanel, 'text');
            app.EditField_37.Editable = 'off';
            app.EditField_37.Value = 'Sensitivity';
            app.EditField_38 = uieditfield(app.ResultsPanel, 'text');
            app.EditField_38.Editable = 'off';
            app.EditField_38.Value = 'Specificity';
            app.EditField_35 = uieditfield(app.UIFigure, 'text');
            app.EditField_35.Editable = 'off';
            app.EditField_35.Value = 'Prediction';
            app.EditField_36 = uieditfield(app.UIFigure, 'text');
            app.EditField_36.Editable = 'off';
            app.EditField_36.Value = 'Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end